classdef optimal_thickness < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UnitsDropDownLabel              matlab.ui.control.Label
        UnitsDropDown                   matlab.ui.control.DropDown
        ThicknessButton                 matlab.ui.control.Button
        ThicknessEditField              matlab.ui.control.NumericEditField
        PhaseShiftEditFieldLabel        matlab.ui.control.Label
        PhaseShiftEditField             matlab.ui.control.NumericEditField
        OptimalThicknessLabel           matlab.ui.control.Label
        MaterialsDropDownLabel          matlab.ui.control.Label
        MaterialsDropDown               matlab.ui.control.DropDown
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        NumericalApertureEditFieldLabel  matlab.ui.control.Label
        NumericalApertureEditField      matlab.ui.control.NumericEditField
        PitchnmEditFieldLabel           matlab.ui.control.Label
        PitchnmEditField                matlab.ui.control.NumericEditField
        MaskAxis                        matlab.ui.control.UIAxes
        ImageButton                     matlab.ui.control.Button
        AerialImageAxes                 matlab.ui.control.UIAxes
        AerialImageLabel                matlab.ui.control.Label
        WaferLabel                      matlab.ui.control.Label
        WaferAxes                       matlab.ui.control.UIAxes
        PhotonCountEditFieldLabel       matlab.ui.control.Label
        PhotonCountEditField            matlab.ui.control.NumericEditField
        ThresholddecimalEditFieldLabel  matlab.ui.control.Label
        ThresholddecimalEditField       matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        materials 
        deltas
        betas
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         [materials, deltas, betas] = parseIndexFromDataFile('data/euv-materials.xlsx');

            % Create MaterialsDropDown
            app.MaterialsDropDown.Items = materials;
            
            app.materials = materials;
            app.deltas = deltas;
            app.betas = betas;
            
            % set default parameters
            
            app.WavelengthnmEditField.Value = 13.5;
            app.NumericalApertureEditField.Value = .33;
            app.PhaseShiftEditField.Value = 3.14;
            app.PitchnmEditField.Value = 200;
            app.PhotonCountEditField.Value = 200;
            app.ThresholddecimalEditField.Value = .5;
            app.NEditField.Value = 100;
            
        end

        % Value changed function: UnitsDropDown
        function UnitsDropDownValueChanged(app, event)
            % do nothing
        end

        % Button pushed function: ThicknessButton
        function ThicknessButtonPushed(app, event)
                        
            % determine what delta corresponds to that material
            delta = app.deltas(strcmp(app.materials, app.MaterialsDropDown.Value));
            
            % determine real part of index of refraction
            n2 = 1 + delta;
            
            % 1) ask what the phase shift value is
            phaseShift = app.PhaseShiftEditField.Value;
            
            % 2) figure out what the correct unit is, and compute
            % phaseshift in correct unit
            unit = app.UnitsDropDown.Value;
            
            if strcmp(unit, 'radians') 
                phaseShift = app.PhaseShiftEditField.Value;
            elseif strcmp(unit, 'degrees')
                phaseShift = app.PhaseShiftEditField.Value*pi/180;
            elseif strcmp(unit, 'waves')
                phaseShift = app.PhaseShiftEditField.Value*2*pi;
            end
            
            % 3) compute thickness using correct phase shift unit          
            n1 = 1; % vacuum
            theta1 = 6*pi/180; % 6 degrees in radians
            theta2 = asin(n1/n2*sin(theta1));
            d = phaseShift*app.WavelengthnmEditField.Value/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
            
            % if d is negative, then we know it's too small and must find
            % the next solution that is positive
            counter = 0;
            while (d<0)
                phaseShift = phaseShift-(2*pi);
                d = phaseShift*app.WavelengthnmEditField.Value/(4*pi*(n1/cos(theta1)-n2/cos(theta2)));
                counter = counter+1;
                if (counter>50) % likely in an infinite loop
                    break
                end
            end
            
            % display optimal thickness
            app.ThicknessEditField.Value = d;
        end

        % Button pushed function: ImageButton
        function ImageButtonPushed(app, event)
            %{ 
            conversion factor:
            1 pixel = lambda/(5*pitch)
            %}
            
            %{
            mask
            %}
            
            % define square function
            
            square = @(x).5*sign(sin(x))+.5;
            
            x = linspace(0, 3*app.PitchnmEditField.Value, app.NEditField.Value);
            
            % convert to cutoff to pixel value
            cutoff = app.NumericalApertureEditField.Value/(app.WavelengthnmEditField.Value/app.PitchnmEditField.Value)*5;
            
            % define diffraction grating with given parameters
            grating = square(x*2*pi/app.PitchnmEditField.Value);
         
            % determine what beta corresponds to that material
            beta = app.betas(strcmp(app.materials, app.MaterialsDropDown.Value));
            
            % determine what delta corresponds to that material
            delta = app.deltas(strcmp(app.materials, app.MaterialsDropDown.Value));
            
            % find phase shift of light passing through absorber
            phaseShift = ((2*pi*2*app.ThicknessEditField.Value)/ ...
                app.WavelengthnmEditField.Value)*(delta);
            
            % find attenuation of intensity as light passes through
            % absorber
            attenuation = exp(-2*pi*beta*2*app.ThicknessEditField.Value/app.WavelengthnmEditField.Value);
            grating(grating == 0) = exp(1i*phaseShift)*(attenuation);
            
           % plot diffraction grating
            plot(app.MaskAxis, x, grating)
            
            %{
            aerial image
            %}
            
            % take FFT of grating
            abs(fftshift(fft(grating)));
            
            % define aperture function
            apertureFxn = zeros(1, app.NEditField.Value);
            apertureFxn((app.NEditField.Value./2)-cutoff:(app.NEditField.Value./2)+cutoff) = 1;
            
            % multiply FFT of grating with aperture function
            image = fftshift(fft(grating)).*(apertureFxn);
            
            % take inverse FFT of image and plot
            plot(app.AerialImageAxes, x, abs((ifft(ifftshift((image))))).^2)
            aerialImage = abs((ifft(ifftshift((image))))).^2;
            
            %{
            wafer
            %}
            
            % n*n matrix of aerial image rows
            aerialImage = ones(size(aerialImage))'*aerialImage;
            aerialImage = aerialImage./max(aerialImage(:)).*app.PhotonCountEditField.Value;
            
            % take into account shot noise
            aerialImage = aerialImage+sqrt(aerialImage).*randn(size(aerialImage));
            waferImage = aerialImage;
            waferImage(waferImage<app.PhotonCountEditField.Value*app.ThresholddecimalEditField.Value) = 0;
            waferImage(waferImage>=app.PhotonCountEditField.Value*app.ThresholddecimalEditField.Value) = 1;
            imagesc(app.WaferAxes, waferImage)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UnitsDropDownLabel = uilabel(app.UIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UIFigure);
            app.UnitsDropDown.Items = {'radians', 'degrees', 'waves', ''};
            app.UnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @UnitsDropDownValueChanged, true);
            app.UnitsDropDown.Value = 'radians';
            app.ThicknessButton = uibutton(app.UIFigure, 'push');
            app.ThicknessButton.ButtonPushedFcn = createCallbackFcn(app, @ThicknessButtonPushed, true);
            app.ThicknessButton.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseShiftEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseShiftEditFieldLabel.Text = 'Phase Shift';
            app.PhaseShiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.OptimalThicknessLabel = uilabel(app.UIFigure);
            app.OptimalThicknessLabel.Text = 'Optimal Thickness';
            app.MaterialsDropDownLabel = uilabel(app.UIFigure);
            app.MaterialsDropDownLabel.Text = 'Materials';
            app.MaterialsDropDown = uidropdown(app.UIFigure);
            app.WavelengthnmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericalApertureEditFieldLabel = uilabel(app.UIFigure);
            app.NumericalApertureEditFieldLabel.Text = 'Numerical Aperture';
            app.NumericalApertureEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchnmEditFieldLabel = uilabel(app.UIFigure);
            app.PitchnmEditFieldLabel.Text = 'Pitch (nm)';
            app.PitchnmEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaskAxis = uiaxes(app.UIFigure);
            title(app.MaskAxis, 'Mask')
            xlabel(app.MaskAxis, '')
            ylabel(app.MaskAxis, '')
            app.ImageButton = uibutton(app.UIFigure, 'push');
            app.ImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImageButtonPushed, true);
            app.ImageButton.Text = 'Image';
            app.AerialImageAxes = uiaxes(app.UIFigure);
            title(app.AerialImageAxes, 'Aerial Image')
            xlabel(app.AerialImageAxes, '')
            ylabel(app.AerialImageAxes, '')
            app.AerialImageLabel = uilabel(app.UIFigure);
            app.AerialImageLabel.Text = 'Predicted Image';
            app.WaferLabel = uilabel(app.UIFigure);
            app.WaferLabel.Text = '___________________________________________________________________________________________________________';
            app.WaferAxes = uiaxes(app.UIFigure);
            title(app.WaferAxes, 'Wafer')
            xlabel(app.WaferAxes, '')
            ylabel(app.WaferAxes, '')
            app.PhotonCountEditFieldLabel = uilabel(app.UIFigure);
            app.PhotonCountEditFieldLabel.Text = 'Photon Count';
            app.PhotonCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholddecimalEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholddecimalEditFieldLabel.Text = 'Threshold (decimal)';
            app.ThresholddecimalEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimal_thickness

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end